classdef GPS_Interface_Artyom_Andreychenko_336229646 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PauseButton                 matlab.ui.control.Button
        nodataLabel                 matlab.ui.control.Label
        TimeLabel                   matlab.ui.control.Label
        TotaldistancetraveledLabel  matlab.ui.control.Label
        AnimateButton               matlab.ui.control.Button
        plotDataButton              matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        SelectedFileLabel           matlab.ui.control.Label
        SelectFileButton            matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        gpsData = [];
        selectedFile = '';
        status = true;
    end
    
    methods (Access = public)
        
        function total_distance = calculate_total_distance(~,latitudes, longitudes)
            % This function takes GPS data and calculates the distance 
            % you have traveled in meters.
            % Convert latitude and longitude from degrees to radians
            latitudes_rad = deg2rad(latitudes);
            longitudes_rad = deg2rad(longitudes);
        
            % Earth radius in meters
            R = 6371000;
        
            % Initialize total distance
            total_distance = 0;
        
            % Calculate distance between consecutive points and sum them up
            for i = 1:length(latitudes)-1
                lat1 = latitudes_rad(i);
                lon1 = longitudes_rad(i);
                lat2 = latitudes_rad(i+1);
                lon2 = longitudes_rad(i+1);
                
                % Haversine formula
                dlat = lat2 - lat1;
                dlon = lon2 - lon1;
                a = sin(dlat/2)^2 + cos(lat1) * cos(lat2) * sin(dlon/2)^2;
                c = 2 * atan2(sqrt(a), sqrt(1-a));
                distance = R * c;
                
                % Add distance to total
                total_distance = total_distance + distance;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', 'Select GPS Data File');
        if filename ~= 0
            app.selectedFile = fullfile(pathname, filename);
            app.Label.Text = app.selectedFile;
           
            % Load GPS data
            app.gpsData = load(app.selectedFile);
        end
        end

        % Button pushed function: plotDataButton
        function plotDataButtonPushed(app, event)

            if ~isempty(app.gpsData)
                % Extract GPS data
                longitude = app.gpsData.Position.longitude;
                latitude = app.gpsData.Position.latitude;
                
                % Plot data
                plot(app.UIAxes, longitude, latitude, 'b.');

                % Calculate and display total distance
                totalDistance = app.calculate_total_distance(latitude,longitude); 
                app.nodataLabel.Text=[num2str(totalDistance),' meters'];
            else
                errordlg('No file selected. Please select a file first.', 'Error');
            end   
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            if ~isempty(app.gpsData)
                % Extract GPS data
                longitude = app.gpsData.Position.longitude;
                latitude = app.gpsData.Position.latitude;
                time = app.gpsData.Position.Timestamp;

                % Plot initial point
                h = plot(app.UIAxes, longitude(1), latitude(1), 'ro');
                hold on;
                h_line=animatedline(app.UIAxes,'Color', 'b', 'LineWidth', 2);
                hold off;
                app.UIAxes.XLim=[min(longitude)*0.99999,max(longitude)*1.00001];
                app.UIAxes.YLim=[min(latitude)*0.99999,max(latitude)*1.00001];


                % Animate movement
                for i = 2:length(longitude)
                    h.XData=longitude(i);
                    h.YData=latitude(i);
                    addpoints(h_line, longitude(i), latitude(i));
                    while ~app.status
                     pause(0.2);  
                    end
                    
                    drawnow;
                    %app.UIAxes.CameraPosition(longitude(i),latitude(i),0);
                    %if app.status
                    pause(0.1);
                    %end
                end

                % Calculate and display total time of movement
                totalTime = time(end) - time(1);
                app.TimeLabel.Text='Time: '+string(totalTime);

                
            else
                errordlg('No file selected. Please select a file first.', 'Error');
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.status == true
                app.status=false;
            else
                app.status = true;

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'GPS Data')
            xlabel(app.UIAxes, 'Longitude')
            ylabel(app.UIAxes, 'Latitude')
            zlabel(app.UIAxes, 'Z')
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.SelectedFileLabel = uilabel(app.UIFigure);
            app.SelectedFileLabel.Text = 'Selected File:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.plotDataButton = uibutton(app.UIFigure, 'push');
            app.plotDataButton.ButtonPushedFcn = createCallbackFcn(app, @plotDataButtonPushed, true);
            app.plotDataButton.Text = 'plotData';
            app.AnimateButton = uibutton(app.UIFigure, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.TotaldistancetraveledLabel = uilabel(app.UIFigure);
            app.TotaldistancetraveledLabel.Text = 'Total distance traveled:';
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = 'Time:';
            app.nodataLabel = uilabel(app.UIFigure);
            app.nodataLabel.Text = 'no data...';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GPS_Interface_Artyom_Andreychenko_336229646

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end